classdef FastDehazeImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        SelectImageButton     matlab.ui.control.Button
        KernelSpinnerLabel    matlab.ui.control.Label
        KernelSpinner         matlab.ui.control.Spinner
        OmegaSpinnerLabel     matlab.ui.control.Label
        OmegaSpinner          matlab.ui.control.Spinner
        wEditFieldLabel       matlab.ui.control.Label
        wEditField            matlab.ui.control.NumericEditField
        pEditFieldLabel       matlab.ui.control.Label
        pEditField            matlab.ui.control.NumericEditField
        WSwitchLabel          matlab.ui.control.Label
        WSwitch               matlab.ui.control.Switch
        VSwitchLabel          matlab.ui.control.Label
        VSwitch               matlab.ui.control.Switch
        V_RSwitchLabel        matlab.ui.control.Label
        V_RSwitch             matlab.ui.control.Switch
        RunButton             matlab.ui.control.Button
        Sigma_tSliderLabel    matlab.ui.control.Label
        Sigma_t               matlab.ui.control.Slider
        Sigma_rSlider_2Label  matlab.ui.control.Label
        Sigma_r               matlab.ui.control.Slider
        RSwitchLabel          matlab.ui.control.Label
        RSwitch               matlab.ui.control.Switch
        tSwitchLabel          matlab.ui.control.Label
        tSwitch               matlab.ui.control.Switch
        Adapt_EQSwitchLabel   matlab.ui.control.Label
        Adapt_EQSwitch        matlab.ui.control.Switch
        JSwitchLabel          matlab.ui.control.Label
        JSwitch               matlab.ui.control.Switch
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imageData = []
        imageW = []
        imageV = []
        imageV_R = []
        imageR = []
        imaget = []
        imageJ = []
        imageJ_adapt = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)

            close all;

            [file ,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif';'*.*'},'File Selector');
            selectedfile = fullfile(path,file);
            
            % Check if a file was selected
            if isequal(file, 0) % No file selected
                msgbox('There is NO image selected');
            else
                % Read the image file
                app.imageData=imread(selectedfile);
                imshow(app.imageData, 'Parent', app.UIAxes);
                [height, width, ~] = size(app.imageData);
                app.UIAxes.XLim = [0 width];
                app.UIAxes.YLim = [0 height];
                app.KernelSpinner.Enable='on';app.KernelSpinnerLabel.Enable='on';
                app.OmegaSpinner.Enable='on';app.OmegaSpinnerLabel.Enable='on';
                app.Sigma_r.Enable='on';app.Sigma_rSlider_2Label.Enable='on';
                app.Sigma_t.Enable='on';app.Sigma_tSliderLabel.Enable='on';
                app.pEditField.Enable='on';app.pEditFieldLabel.Enable='on';
                app.wEditField.Enable='on';app.wEditFieldLabel.Enable='on';
                app.tSwitch.Enable='on';app.tSwitchLabel.Enable='on';
                app.V_RSwitch.Enable='on';app.V_RSwitchLabel.Enable='on';
                app.RSwitch.Enable='on';app.RSwitchLabel.Enable='on';
                app.VSwitch.Enable='on';app.VSwitchLabel.Enable='on';
                app.WSwitch.Enable='on';app.WSwitchLabel.Enable='on';
                app.JSwitch.Enable='on';app.JSwitchLabel.Enable='on';
                app.Adapt_EQSwitch.Enable='on';app.Adapt_EQSwitchLabel.Enable='on';
                app.RunButton.Enable='on';
            end
         
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            close all;
            [height, width, ~] = size(app.imageData);
            %%
            sigma_s=0.03*min(height,width);
            sigma_r=app.Sigma_r.Value;
            sigma_t=app.Sigma_t.Value;
            p=app.pEditField.Value;
            w=app.wEditField.Value;
            t0=0.3;
            radius = app.KernelSpinner.Value;
            omega(1:2) = app.OmegaSpinner.Value;
            
            %%
            app.imageW = double(min(app.imageData,[],3));
            if strcmp(app.WSwitch.Value,'On')
               figure,imshow(uint8(app.imageW)),title('W'); 
            end
            
            
            %%
            B = medfilt2(app.imageW,omega,'symmetric');
            C=B-medfilt2(abs(app.imageW-B),omega,'symmetric');
            app.imageV=max(min(p.*C,app.imageW),0);
            if strcmp(app.VSwitch.Value,'On')
               figure,imshow(uint8(app.imageV)),title('V');
            end   
            %%
            app.imageR=bilat_filter(app.imageW,radius,sigma_s,sigma_r);
            if strcmp(app.RSwitch.Value,'On')
               figure,imshow(uint8(app.imageR)),title('R');
            end   
       
            %%
            app.imageV_R=bilat_filter_joint(app.imageV,app.imageR,radius,sigma_s,sigma_r,sigma_t);
            if strcmp(app.V_RSwitch.Value,'On')
               figure,imshow(uint8(app.imageV_R)),title('V_{R}');
            end   
            %%
            A  = min([estimateAtmosphericLight(app.imageW), max(max(255-app.imageW))]);
            app.imaget=ones(height,width)-w*app.imageV_R/A;
            if strcmp(app.tSwitch.Value,'On')
               figure,imshow(app.imaget),title('depth image t');
            end
            image_double=double(app.imageData);
            app.imageJ=zeros(size(app.imageData));
            app.imageJ(:,:,1)=(image_double(:,:,1)-A)./max(app.imaget,t0)+A;
            app.imageJ(:,:,2)=(image_double(:,:,2)-A)./max(app.imaget,t0)+A;
            app.imageJ(:,:,3)=(image_double(:,:,3)-A)./max(app.imaget,t0)+A;
    
            imshow(uint8(app.imageJ), 'Parent', app.UIAxes_2);
            [height, width, ~] = size(app.imageData);
            app.UIAxes_2.XLim = [0 width];
            app.UIAxes_2.YLim = [0 height];
            if strcmp(app.JSwitch.Value,'On')
               figure,imshow(uint8(app.imageJ)),title('J');
               figure,imshow(uint8(app.imageData)),title('Original');
            end
            
            %%
            if strcmp(app.Adapt_EQSwitch.Value,'On')
               LAB = rgb2lab(uint8(app.imageJ)); 
               L = LAB(:,:,1)/100;
               L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
               LAB(:,:,1) = L*100;
               app.imageJ_adapt = lab2rgb(LAB);
               figure,imshow(app.imageJ_adapt),title('J_{adaptEQ}');
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectImageButton = uibutton(app.LeftPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.KernelSpinnerLabel = uilabel(app.LeftPanel);
            app.KernelSpinnerLabel.Enable = 'off';
            app.KernelSpinnerLabel.Text = 'Kernel';
            app.KernelSpinner = uispinner(app.LeftPanel);
            app.KernelSpinner.Step = 2;
            app.KernelSpinner.Limits = [1 50];
            app.KernelSpinner.RoundFractionalValues = 'on';
            app.KernelSpinner.Enable = 'off';
            app.KernelSpinner.Value = 15;
            app.OmegaSpinnerLabel = uilabel(app.LeftPanel);
            app.OmegaSpinnerLabel.Enable = 'off';
            app.OmegaSpinnerLabel.Text = 'Omega';
            app.OmegaSpinner = uispinner(app.LeftPanel);
            app.OmegaSpinner.Step = 2;
            app.OmegaSpinner.Limits = [1 50];
            app.OmegaSpinner.RoundFractionalValues = 'on';
            app.OmegaSpinner.Enable = 'off';
            app.OmegaSpinner.Value = 15;
            app.wEditFieldLabel = uilabel(app.LeftPanel);
            app.wEditFieldLabel.Enable = 'off';
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.LeftPanel, 'numeric');
            app.wEditField.Limits = [0.5 1];
            app.wEditField.Enable = 'off';
            app.wEditField.Value = 0.95;
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Enable = 'off';
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pEditField.Limits = [0.5 1];
            app.pEditField.Enable = 'off';
            app.pEditField.Value = 0.95;
            app.WSwitchLabel = uilabel(app.LeftPanel);
            app.WSwitchLabel.Enable = 'off';
            app.WSwitchLabel.Text = 'W';
            app.WSwitch = uiswitch(app.LeftPanel, 'slider');
            app.WSwitch.Enable = 'off';
            app.VSwitchLabel = uilabel(app.LeftPanel);
            app.VSwitchLabel.Enable = 'off';
            app.VSwitchLabel.Text = 'V';
            app.VSwitch = uiswitch(app.LeftPanel, 'slider');
            app.VSwitch.Enable = 'off';
            app.V_RSwitchLabel = uilabel(app.LeftPanel);
            app.V_RSwitchLabel.Enable = 'off';
            app.V_RSwitchLabel.Text = 'V_R';
            app.V_RSwitch = uiswitch(app.LeftPanel, 'slider');
            app.V_RSwitch.Enable = 'off';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.Sigma_tSliderLabel = uilabel(app.LeftPanel);
            app.Sigma_tSliderLabel.Enable = 'off';
            app.Sigma_tSliderLabel.Text = 'Sigma_t';
            app.Sigma_t = uislider(app.LeftPanel);
            app.Sigma_t.Limits = [0.01 100];
            app.Sigma_t.MajorTicks = [0.01 20 40 60 80 100];
            app.Sigma_t.Enable = 'off';
            app.Sigma_t.Value = 20;
            app.Sigma_rSlider_2Label = uilabel(app.LeftPanel);
            app.Sigma_rSlider_2Label.Enable = 'off';
            app.Sigma_rSlider_2Label.Text = 'Sigma_r';
            app.Sigma_r = uislider(app.LeftPanel);
            app.Sigma_r.Limits = [0.01 100];
            app.Sigma_r.MajorTicks = [0.01 20 40 60 80 100];
            app.Sigma_r.Enable = 'off';
            app.Sigma_r.Value = 20;
            app.RSwitchLabel = uilabel(app.LeftPanel);
            app.RSwitchLabel.Enable = 'off';
            app.RSwitchLabel.Text = 'R';
            app.RSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RSwitch.Enable = 'off';
            app.tSwitchLabel = uilabel(app.LeftPanel);
            app.tSwitchLabel.Enable = 'off';
            app.tSwitchLabel.Text = 't';
            app.tSwitch = uiswitch(app.LeftPanel, 'slider');
            app.tSwitch.Enable = 'off';
            app.Adapt_EQSwitchLabel = uilabel(app.LeftPanel);
            app.Adapt_EQSwitchLabel.Enable = 'off';
            app.Adapt_EQSwitchLabel.Text = 'Adapt_EQ';
            app.Adapt_EQSwitch = uiswitch(app.LeftPanel, 'slider');
            app.Adapt_EQSwitch.Enable = 'off';
            app.JSwitchLabel = uilabel(app.LeftPanel);
            app.JSwitchLabel.Enable = 'off';
            app.JSwitchLabel.Text = 'J';
            app.JSwitch = uiswitch(app.LeftPanel, 'slider');
            app.JSwitch.Enable = 'off';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = '2023-05-24_22h40_17.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Dehazed (J)')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FastDehazeImage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end