classdef HomomorphicDehaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        HistogramButton           matlab.ui.control.Button
        Dehazed_RGBSwitchLabel    matlab.ui.control.Label
        Dehazed_GraySwitch        matlab.ui.control.Switch
        Dehazed_GraySwitchLabel   matlab.ui.control.Label
        GI_FFTSwitch              matlab.ui.control.Switch
        GI_FFTSwitchLabel         matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        GausHPTab                 matlab.ui.container.Tab
        CEditField                matlab.ui.control.NumericEditField
        CEditFieldLabel           matlab.ui.control.Label
        gHEditField               matlab.ui.control.NumericEditField
        gHEditFieldLabel          matlab.ui.control.Label
        gLEditField               matlab.ui.control.NumericEditField
        gLEditFieldLabel          matlab.ui.control.Label
        D0EditField               matlab.ui.control.NumericEditField
        D0EditFieldLabel          matlab.ui.control.Label
        ButterHPTab               matlab.ui.container.Tab
        NEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel           matlab.ui.control.Label
        D0EditField_2             matlab.ui.control.NumericEditField
        D0EditField_2Label        matlab.ui.control.Label
        IdealHPTab                matlab.ui.container.Tab
        D0EditField_3             matlab.ui.control.NumericEditField
        D0EditField_3Label        matlab.ui.control.Label
        Adapt_EQSwitch            matlab.ui.control.Switch
        Adapt_EQSwitchLabel       matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        I_FFTSwitch               matlab.ui.control.Switch
        I_FFTSwitchLabel          matlab.ui.control.Label
        DispSelectedFilterButton  matlab.ui.control.Button
        SelectImageButton         matlab.ui.control.Button
        Dehazed_RGBSwitch         matlab.ui.control.Switch
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imageData = []
        I_gray =[]
        I_gray_defog = []
        H = []
        H_selected =[]
        I_defog = []
        imageJ_adapt = []
    end
    
    methods (Access = private)
        
        function Update_H(app)
                app.Dehazed_GraySwitch.Enable='on';app.Dehazed_GraySwitchLabel.Enable='on';
                app.Dehazed_RGBSwitch.Enable='on';app.Dehazed_RGBSwitchLabel.Enable='on';
                app.Adapt_EQSwitch.Enable='on';app.Adapt_EQSwitchLabel.Enable='on';
                app.GI_FFTSwitch.Enable='on';app.GI_FFTSwitchLabel.Enable='on';
                app.I_FFTSwitch.Enable='on';app.I_FFTSwitchLabel.Enable='on';
                app.DispSelectedFilterButton.Enable='on';
                app.RunButton.Enable='on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)

            close all;

            [file ,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif';'*.*'},'File Selector');
            selectedfile = fullfile(path,file);
            
            % Check if a file was selected
            if isequal(file, 0) % No file selected
                msgbox('There is NO image selected');
            else
                % Read the image file
                app.imageData=imread(selectedfile);
                imshow(app.imageData, 'Parent', app.UIAxes);
                [height, width, ~] = size(app.imageData);
                app.UIAxes.XLim = [0 width];
                app.UIAxes.YLim = [0 height];
                app.TabGroup.Visible='on';
                app.RunButton.Enable='on';
                app.HistogramButton.Enable='on';

                app.imageData = im2double(app.imageData);
                app.I_gray = rgb2gray(app.imageData);
            end
         
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            close all;
            %%
            [app.I_gray_defog ,If,G_I_FFT] = homomorphic_filter(app.I_gray,app.H);

            if strcmp(app.I_FFTSwitch.Value,'On')
               figure,imshow(If),title('I_{FFT}'); 
            end
            if strcmp(app.GI_FFTSwitch.Value,'On')
               figure,imshow(G_I_FFT),title('G*I_{FFT}'); 
            end

            if strcmp(app.Dehazed_GraySwitch.Value,'On')
               figure,imshow(app.I_gray_defog),title('Dehazed Gray');
            end 


            %% 
            app.I_defog = zeros(size(app.imageData));
            for i = 1:3
                app.I_defog(:,:,i) = app.imageData(:,:,i).*app.I_gray_defog;
            end
            app.I_defog = rescale(app.I_defog);

            imshow(app.I_defog, 'Parent', app.UIAxes_2);
            [height, width, ~] = size(app.I_defog);
            app.UIAxes_2.XLim = [0 width];
            app.UIAxes_2.YLim = [0 height];
            if strcmp(app.Dehazed_RGBSwitch.Value,'On')
               figure,imshow(app.I_defog),title('Dehazed');
            end 
  
            %%
            if strcmp(app.Adapt_EQSwitch.Value,'On')
               LAB = rgb2lab(uint8(app.I_defog*255)); 
               L = LAB(:,:,1)/100;
               L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
               LAB(:,:,1) = L*100;
               app.imageJ_adapt = lab2rgb(LAB);
               figure,imshow(app.imageJ_adapt),title('J_{adaptEQ}');
            end
            
        end

        % Callback function: CEditField, D0EditField, GausHPTab, GausHPTab,
        % 
        % ...and 2 other components
        function GausHPTabButtonDown(app, event)
          gL = app.gLEditField.Value;
          gH = app.gHEditField.Value;
          C  = app.CEditField.Value;
          D0 = app.D0EditField.Value;
          app.H = gaushp(app.I_gray, gL, gH, D0, C);  
          app.Update_H()
        end

        % Callback function: ButterHPTab, ButterHPTab, D0EditField_2, 
        % ...and 1 other component
        function ButterHPTabButtonDown(app, event)
          n  = app.NEditField.Value;
          D0 = app.D0EditField_2.Value;
          app.H = butterhp(app.I_gray, D0,n);
          app.Update_H()
        end

        % Callback function: D0EditField_3, IdealHPTab, IdealHPTab
        function IdealHPTabButtonDown(app, event)
          D0 = app.D0EditField_3.Value;
          app.H = idealhp(app.I_gray, D0);
          app.Update_H()
        end

        % Button pushed function: DispSelectedFilterButton
        function DispSelectedFilterButtonPushed(app, event)
            close all;
            plotTFSurface(app.H);
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            figure;imhist(app.I_gray);title('Histogram');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Dehazed_RGBSwitch = uiswitch(app.LeftPanel, 'slider');
            app.Dehazed_RGBSwitch.Enable = 'off';
            app.SelectImageButton = uibutton(app.LeftPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.DispSelectedFilterButton = uibutton(app.LeftPanel, 'push');
            app.DispSelectedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DispSelectedFilterButtonPushed, true);
            app.DispSelectedFilterButton.Enable = 'off';
            app.DispSelectedFilterButton.Text = 'Disp Selected Filter';
            app.I_FFTSwitchLabel = uilabel(app.LeftPanel);
            app.I_FFTSwitchLabel.Enable = 'off';
            app.I_FFTSwitchLabel.Text = 'I_FFT';
            app.I_FFTSwitch = uiswitch(app.LeftPanel, 'slider');
            app.I_FFTSwitch.Enable = 'off';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.Adapt_EQSwitchLabel = uilabel(app.LeftPanel);
            app.Adapt_EQSwitchLabel.Enable = 'off';
            app.Adapt_EQSwitchLabel.Text = 'Adapt_EQ';
            app.Adapt_EQSwitch = uiswitch(app.LeftPanel, 'slider');
            app.Adapt_EQSwitch.Enable = 'off';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.Visible = 'off';
            app.GausHPTab = uitab(app.TabGroup);
            app.GausHPTab.SizeChangedFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.GausHPTab.Title = 'GausHP';
            app.GausHPTab.ButtonDownFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.D0EditFieldLabel = uilabel(app.GausHPTab);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.GausHPTab, 'numeric');
            app.D0EditField.Limits = [1 100];
            app.D0EditField.ValueChangedFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.D0EditField.Value = 1;
            app.gLEditFieldLabel = uilabel(app.GausHPTab);
            app.gLEditFieldLabel.Text = 'gL';
            app.gLEditField = uieditfield(app.GausHPTab, 'numeric');
            app.gLEditField.Limits = [0.09 1];
            app.gLEditField.ValueChangedFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.gLEditField.Value = 0.1;
            app.gHEditFieldLabel = uilabel(app.GausHPTab);
            app.gHEditFieldLabel.Text = 'gH';
            app.gHEditField = uieditfield(app.GausHPTab, 'numeric');
            app.gHEditField.Limits = [1 4];
            app.gHEditField.ValueChangedFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.gHEditField.Value = 1.1;
            app.CEditFieldLabel = uilabel(app.GausHPTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.GausHPTab, 'numeric');
            app.CEditField.Limits = [1 100];
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @GausHPTabButtonDown, true);
            app.CEditField.Value = 2;
            app.ButterHPTab = uitab(app.TabGroup);
            app.ButterHPTab.SizeChangedFcn = createCallbackFcn(app, @ButterHPTabButtonDown, true);
            app.ButterHPTab.Title = 'ButterHP';
            app.ButterHPTab.ButtonDownFcn = createCallbackFcn(app, @ButterHPTabButtonDown, true);
            app.D0EditField_2Label = uilabel(app.ButterHPTab);
            app.D0EditField_2Label.Text = 'D0';
            app.D0EditField_2 = uieditfield(app.ButterHPTab, 'numeric');
            app.D0EditField_2.Limits = [1 100];
            app.D0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ButterHPTabButtonDown, true);
            app.D0EditField_2.Value = 1;
            app.NEditFieldLabel = uilabel(app.ButterHPTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.ButterHPTab, 'numeric');
            app.NEditField.Limits = [1 100];
            app.NEditField.RoundFractionalValues = 'on';
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @ButterHPTabButtonDown, true);
            app.NEditField.Value = 2;
            app.IdealHPTab = uitab(app.TabGroup);
            app.IdealHPTab.SizeChangedFcn = createCallbackFcn(app, @IdealHPTabButtonDown, true);
            app.IdealHPTab.Title = 'IdealHP';
            app.IdealHPTab.ButtonDownFcn = createCallbackFcn(app, @IdealHPTabButtonDown, true);
            app.D0EditField_3Label = uilabel(app.IdealHPTab);
            app.D0EditField_3Label.Text = 'D0';
            app.D0EditField_3 = uieditfield(app.IdealHPTab, 'numeric');
            app.D0EditField_3.Limits = [1 100];
            app.D0EditField_3.RoundFractionalValues = 'on';
            app.D0EditField_3.ValueChangedFcn = createCallbackFcn(app, @IdealHPTabButtonDown, true);
            app.D0EditField_3.Value = 1;
            app.GI_FFTSwitchLabel = uilabel(app.LeftPanel);
            app.GI_FFTSwitchLabel.Enable = 'off';
            app.GI_FFTSwitchLabel.Text = 'G*I_FFT';
            app.GI_FFTSwitch = uiswitch(app.LeftPanel, 'slider');
            app.GI_FFTSwitch.Enable = 'off';
            app.Dehazed_GraySwitchLabel = uilabel(app.LeftPanel);
            app.Dehazed_GraySwitchLabel.Enable = 'off';
            app.Dehazed_GraySwitchLabel.Text = 'Dehazed_Gray';
            app.Dehazed_GraySwitch = uiswitch(app.LeftPanel, 'slider');
            app.Dehazed_GraySwitch.Enable = 'off';
            app.Dehazed_RGBSwitchLabel = uilabel(app.LeftPanel);
            app.Dehazed_RGBSwitchLabel.Enable = 'off';
            app.Dehazed_RGBSwitchLabel.Text = 'Dehazed_RGB';
            app.HistogramButton = uibutton(app.LeftPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '2023-05-26_18h16_08.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Dehazed')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomomorphicDehaze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end